classdef triad_dissonance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LevelOfDissonanceUIFigure   matlab.ui.Figure
        BassPitchHzLabel            matlab.ui.control.Label
        BassPitchSlider             matlab.ui.control.Slider
        NumberofPartialsLabel       matlab.ui.control.Label
        NumberOfPartialsKnob        matlab.ui.control.DiscreteKnob
        VolumedBKnobLabel           matlab.ui.control.Label
        VolumedBKnob                matlab.ui.control.Knob
        PartialAmplitudeDecayLabel  matlab.ui.control.Label
        PartialAmplitudeSlider      matlab.ui.control.Slider
    end

    
    properties (Access = private)
        deviceWriter = audioDeviceWriter('SampleRate',48000); % audio output
        audioLoopActive = 1; % enable audio loop
        customAxes; %surf axes
        lowerIntervalInCents = 500;
        upperIntervalInCents = 700;
    end
    
    methods (Access = private)
        
        function updateMatrix(app)
            NumberOfPartials = str2double(app.NumberOfPartialsKnob.Value);
            LowerToneFrequency = app.BassPitchSlider.Value;
            PartialAmplitudeDecay = app.PartialAmplitudeSlider.Value;
            [levelOfInstability,Ratios] = computeInstabilityMatrix(NumberOfPartials,LowerToneFrequency,PartialAmplitudeDecay);
            interval = rat2cent(Ratios);
            RI = imref2d(size(levelOfInstability));
            RI.XWorldLimits = [min(interval) max(interval)];
            RI.YWorldLimits = [min(interval) max(interval)];
            h = surf(app.customAxes,interval,interval,levelOfInstability,'EdgeColor','none');
            set(h,'HitTest','off');
            textLabelLevel = max(levelOfInstability(:));
            text(app.customAxes,400,300,textLabelLevel,'m');
            text(app.customAxes,300,400,textLabelLevel,'M');
            text(app.customAxes,400,400,textLabelLevel,'A');
            text(app.customAxes,300,300,textLabelLevel,'d');
            text(app.customAxes,500,400,textLabelLevel,'m');
            text(app.customAxes,500,300,textLabelLevel,'M');
            text(app.customAxes,300,500,textLabelLevel,'m');
            text(app.customAxes,400,500,textLabelLevel,'M');
            view(app.customAxes,[0, 90]);
            xlabel(app.customAxes,'Upper Interval [cents]');
            ylabel(app.customAxes,'Lower Interval [cents]');
            xlim(app.customAxes,[min(interval) max(interval)]);
            ylim(app.customAxes,[min(interval) max(interval)]);
            ch = colorbar(app.customAxes);
            ylabel(ch, 'Level of Dissonance','FontSize',13);
            
        end


        function runAudioLoop(app)
            bass = continuousOscillator;
            tenor = continuousOscillator;
            alt = continuousOscillator;
            
            blockSize = 1024;
            while isvalid(app) && app.audioLoopActive
                mouse_pos = get(app.customAxes,'CurrentPoint');
                app.lowerIntervalInCents = mouse_pos(1,2);
                app.upperIntervalInCents = mouse_pos(1,1);
                gain = db2mag(app.VolumedBKnob.Value)/4;
                
                if app.lowerIntervalInCents > 850 || app.upperIntervalInCents > 850
                   gain = 0.0; 
                end
                
                NumberOfPartials = str2double(app.NumberOfPartialsKnob.Value);
                LowerToneFrequency = app.BassPitchSlider.Value;
                PartialAmplitudeDecay = app.PartialAmplitudeSlider.Value;
                bass.numberOfPartials = NumberOfPartials;
                bass.f0 = LowerToneFrequency;
                bass.partialAmplitudeDecay = PartialAmplitudeDecay;
                tenor.numberOfPartials = NumberOfPartials;
                tenor.f0 = LowerToneFrequency * cent2rat(app.lowerIntervalInCents);
                tenor.partialAmplitudeDecay = PartialAmplitudeDecay;
                alt.numberOfPartials = NumberOfPartials;
                alt.f0 = tenor.f0 * cent2rat(app.upperIntervalInCents);
                alt.partialAmplitudeDecay = PartialAmplitudeDecay;
                
                audioOut = (bass.getSignal(blockSize) + tenor.getSignal(blockSize) + alt.getSignal(blockSize)) .* gain;
                app.deviceWriter(audioOut);
                drawnow limitrate
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            app.customAxes = axes('Parent',app.LevelOfDissonanceUIFigure,'Position',[0.1 0.1 0.6 0.8]);
            addpath(genpath('./'));
            updateMatrix(app);
            runAudioLoop(app);
        end

        % Value changed function: BassPitchSlider
        function BassPitchSliderValueChanged(app, event)
            updateMatrix(app);
        end

        % Value changed function: NumberOfPartialsKnob
        function NumberOfPartialsKnobValueChanged(app, event)
            updateMatrix(app);
        end

        % Close request function: LevelOfDissonanceUIFigure
        function LevelOfDissonanceUIFigureCloseRequest(app, event)
            release(app.deviceWriter);
            delete(app)
        end

        % Window button motion function: LevelOfDissonanceUIFigure
        function LevelOfDissonanceUIFigureWindowButtonMotion(app, event)
            
        end

        % Callback function
        function DissonanceTypeSliderValueChanged(app, event)
            updateMatrix(app);
        end

        % Value changed function: PartialAmplitudeSlider
        function PartialAmplitudeSliderValueChanged(app, event)
            updateMatrix(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LevelOfDissonanceUIFigure = uifigure('Visible', 'off');
            app.LevelOfDissonanceUIFigure.Name = 'Level Of Dissonance';
            app.LevelOfDissonanceUIFigure.CloseRequestFcn = createCallbackFcn(app, @LevelOfDissonanceUIFigureCloseRequest, true);
            app.LevelOfDissonanceUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @LevelOfDissonanceUIFigureWindowButtonMotion, true);
            app.BassPitchHzLabel = uilabel(app.LevelOfDissonanceUIFigure);
            app.BassPitchHzLabel.Text = 'Bass Pitch [Hz]';
            app.BassPitchSlider = uislider(app.LevelOfDissonanceUIFigure);
            app.BassPitchSlider.Limits = [100 1000];
            app.BassPitchSlider.ValueChangedFcn = createCallbackFcn(app, @BassPitchSliderValueChanged, true);
            app.BassPitchSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.BassPitchSlider.Value = 150;
            app.NumberofPartialsLabel = uilabel(app.LevelOfDissonanceUIFigure);
            app.NumberofPartialsLabel.Text = 'Number of Partials';
            app.NumberOfPartialsKnob = uiknob(app.LevelOfDissonanceUIFigure, 'discrete');
            app.NumberOfPartialsKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.NumberOfPartialsKnob.ValueChangedFcn = createCallbackFcn(app, @NumberOfPartialsKnobValueChanged, true);
            app.NumberOfPartialsKnob.Value = '7';
            app.VolumedBKnobLabel = uilabel(app.LevelOfDissonanceUIFigure);
            app.VolumedBKnobLabel.Text = 'Volume [dB]';
            app.VolumedBKnob = uiknob(app.LevelOfDissonanceUIFigure, 'continuous');
            app.VolumedBKnob.Limits = [-60 0];
            app.VolumedBKnob.Value = -60;
            app.PartialAmplitudeDecayLabel = uilabel(app.LevelOfDissonanceUIFigure);
            app.PartialAmplitudeDecayLabel.Text = 'Partial Amplitude Decay';
            app.PartialAmplitudeSlider = uislider(app.LevelOfDissonanceUIFigure);
            app.PartialAmplitudeSlider.Limits = [0.01 1];
            app.PartialAmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @PartialAmplitudeSliderValueChanged, true);
            app.PartialAmplitudeSlider.Value = 0.88;
            % Show the figure after all components are created
            app.LevelOfDissonanceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = triad_dissonance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LevelOfDissonanceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LevelOfDissonanceUIFigure)
        end
    end
end